classdef MusicWorks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        AddMenu          matlab.ui.container.Menu
        add_file         matlab.ui.container.Menu
        add_folder       matlab.ui.container.Menu
        Hyperlink        matlab.ui.control.Hyperlink
        Image            matlab.ui.control.Image
        StyleDropDown    matlab.ui.control.DropDown
        MusicWorksLabel  matlab.ui.control.Label
        PLAYSPEED        matlab.ui.control.DropDown
        Label_4          matlab.ui.control.Label
        Tree             matlab.ui.container.Tree
        process          matlab.ui.control.Slider
        play_and_pause   matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        extendsions = {'.ogg';'.mp3';'.wav';'.flac';'.au';'.aif';'.aiff';'.mp4';'.m4a'}
        total_frame % length of the video
        fs % sample rate
        player 
        play_speed = 1 
        audio_data 
        my_timer 
        fragment_size = 0.1 
        bar_handler 
    end
    
    methods (Access = private)
        
        function load_node(app, node)
            % get the file and path
            file = node.Text;
            path = node.NodeData;
            full_path = fullfile(path, file);
            % if file exist
            if ~exist(full_path, 'file')
                errordlg("file not exist");
                return;
            end
            % load
            [app.audio_data, app.fs] = audioread(fullfile(path, file));
            pause(app.player);
            % refresh the length
            app.total_frame = size(app.audio_data,1);
            % refresh the player
            app.player = audioplayer(app.audio_data, app.play_speed*app.fs);
            play(app.player);
        end
   
        function node = addNode(app, file, path)
            node = uitreenode(app.Tree);
            node.Text = file;
            node.NodeData = path;
        end
        
        function draw_bar(app)
            half_space = floor(app.fs*app.play_speed*app.fragment_size/2);
            left = app.player.CurrentSample - half_space;
            if left<1
                left = 1;
            end
            right = app.player.CurrentSample + half_space;
            if right>app.total_frame
                right = app.total_frame;
            end
            % calculate the graph
            X = app.audio_data(left:right,:);
            % combine the left and right sound track
            X = sum(X, 2);
            L = length(X);
            Y = fft(X);
            tmp = abs(Y/L);
            P1 = 2*tmp(1:floor(L/2)+1);
            freq = app.fs*app.play_speed*(0:(L/2))/L;
            P2 = P1(freq<2500&freq>20);
            freq2 = freq(freq<2500&freq>20);
            window = floor(length(freq2)/32);
            XData = smooth(freq2, window);
            YData = smooth(P2, window);
            XData = XData(1:window:end);
            YData = YData(1:window:end);
            
            set(app.bar_handler, 'XData', XData, 'YData', YData);
        end
        
        function update_process(app, ~, ~)
            if ~isempty(app.player)&&strcmpi(get(app.player,'running'),'on')
                app.process.Value = app.player.CurrentSample/app.total_frame;
                app.draw_bar();
                
                
                % Summer Mode
                if(strcmp(app.StyleDropDown.Value, 'Summer'))
                    app.UIAxes.Color = [1 1 1];
                    app.UIAxes.ColorOrder = [    0    1    0
                                            0.8500    0.3250    0.0980
                                            0.9290    0.6940    0.1250
                                            0.4940    0.1840    0.5560
                                            0.4660    0.6740    0.1880
                                            0.3010    0.7450    0.9330
                                            0.6350    0.0780    0.1840];
                end

                % Dark Mode
                if(strcmp(app.StyleDropDown.Value, 'Dark'))
                    app.UIAxes.Color = [0.2 0.2 0.2];
                    app.UIAxes.ColorOrder = [    1    1    1
                                            0.8500    0.3250    0.0980
                                            0.9290    0.6940    0.1250
                                            0.4940    0.1840    0.5560
                                            0.4660    0.6740    0.1880
                                            0.3010    0.7450    0.9330
                                            0.6350    0.0780    0.1840];
                end

                if strcmp(app.StyleDropDown.Value, 'Auto')
                    % change the color (counting stars)
                    app.UIAxes.Color = [1 1 1];
                    if app.process.Value > 0.304 && app.process.Value <= 0.365
                        app.UIAxes.ColorOrder = [    1    0.69    0.13
                                            0.8500    0.3250    0.0980
                                            0.9290    0.6940    0.1250
                                            0.4940    0.1840    0.5560
                                            0.4660    0.6740    0.1880
                                            0.3010    0.7450    0.9330
                                            0.6350    0.0780    0.1840];
                    elseif app.process.Value > 0.365 && app.process.Value < 0.442
                          app.UIAxes.ColorOrder = [    1    0    0
                                            0.8500    0.3250    0.0980
                                            0.9290    0.6940    0.1250
                                            0.4940    0.1840    0.5560
                                            0.4660    0.6740    0.1880
                                            0.3010    0.7450    0.9330
                                            0.6350    0.0780    0.1840];  
                    else
                        app.UIAxes.ColorOrder = [    0    0.4470    0.7410
                                            0.8500    0.3250    0.0980
                                            0.9290    0.6940    0.1250
                                            0.4940    0.1840    0.5560
                                            0.4660    0.6740    0.1880
                                            0.3010    0.7450    0.9330
                                            0.6350    0.0780    0.1840];
                    end
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.bar_handler = bar(app.UIAxes, 0, 0);
            ylim(app.UIAxes, [0, 0.2]); 
            app.my_timer = timer("ExecutionMode", "fixedSpacing", 'Period', 0.1, "TimerFcn", @app.update_process);
            start(app.my_timer);
        end

        % Button pushed function: play_and_pause
        function play_and_pauseButtonPushed(app, event)

            if isempty(app.player)
                return
            end
            if strcmpi(get(app.player,'running'),'on')
                pause(app.player);
            else
                resume(app.player);
            end
        end

        % Value changed function: process
        function processValueChanged(app, event)
            value = app.process.Value;
            if isempty(app.player)
                return
            end
            status = strcmpi(get(app.player,'running'),'on');
            if status
                pause(app.player);
            end
            % play from a specific frame
            play(app.player, round(value*app.total_frame));
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            % load audio
            app.load_node(selectedNodes);
        end

        % Menu selected function: add_file
        function add_fileMenuSelected(app, event)
            s = '';
            for index = 1:length(app.extendsions)
                s = strcat(s, '*', app.extendsions{index}, ';');
            end
            [files,path] = uigetfile({s, "audio file"}, 'MultiSelect', 'on');
            % type of file (file, folder)
            if ~ischar(path)
                return
            end
            if ~iscell(files)
                app.addNode(files, path);
                return
            end
            for i = 1:length(files)
                app.addNode(files{i}, path);
            end
        end

        % Callback function
        function add_folderMenuSelected(app, event)
            path = uigetdir;
            if ~ischar(path)
                return
            end
            files = batch_load(path, app.extendsions{:});
            f = waitbar(0,'Adding file...', 'WindowStyle', 'modal');
            try
                for i = 1:length(files)
                    [filepath, name, ext] = fileparts(files{i});
                    app.addNode([name, ext], filepath);
                    if mod(i, 50) == 0
                        waitbar(i/length(files),f);
                    end
                end
                waitbar(1,f);
                close(f);
            catch
            end
        end

        % Value changed function: PLAYSPEED
        function PLAYSPEEDValueChanged(app, event)
            if isempty(app.player)
                return
            end
            status = strcmpi(get(app.player,'running'),'on');
            if status
                pause(app.player);
            end
            current_frame = app.player.CurrentSample;
            app.play_speed = str2double(app.PLAYSPEED.Value);
            app.player.SampleRate = app.play_speed*app.fs;
            if status
                play(app.player, current_frame);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            pause(app.player);
            delete(app.player);
            stop(app.my_timer);
            delete(app.my_timer);
            delete(app)
        end

        % Callback function: not associated with a component
        function HyperlinkClicked(app, event)
            email = 'haowenj@mathworks.com';
            url = ['mailto:',email];
            web(url,'-browser')
        end

        % Menu selected function: add_folder
        function add_folderMenuSelected2(app, event)
            path = uigetdir;
            if ~ischar(path)
                return
            end
            files = batch_load(path, app.extendsions{:});
            f = waitbar(0,'Adding file...', 'WindowStyle', 'modal');
            try
                for i = 1:length(files)
                    [filepath, name, ext] = fileparts(files{i});
                    app.addNode([name, ext], filepath);
                    if mod(i, 50) == 0
                        waitbar(i/length(files),f);
                    end
                end
                waitbar(1,f);
                close(f);
            catch
            end
        end

        % Value changed function: StyleDropDown
        function StyleDropDownValueChanged(app, event)
            value = app.StyleDropDown.Value;
            if strcmp(value, 'Option 2')
                app.UIAxes.ColorOrder = [    0    1    0
                                        0.8500    0.3250    0.0980
                                        0.9290    0.6940    0.1250
                                        0.4940    0.1840    0.5560
                                        0.4660    0.6740    0.1880
                                        0.3010    0.7450    0.9330
                                        0.6350    0.0780    0.1840];
            end
        end

        % Callback function: Hyperlink
        function HyperlinkClicked2(app, event)
            email = 'haowenj@mathworks.com';
            url = ['mailto:',email];
            web(url,'-browser')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddMenu = uimenu(app.UIFigure);
            app.AddMenu.Text = 'Add';
            app.add_file = uimenu(app.AddMenu);
            app.add_file.MenuSelectedFcn = createCallbackFcn(app, @add_fileMenuSelected, true);
            app.add_file.Text = 'File';
            app.add_folder = uimenu(app.AddMenu);
            app.add_folder.MenuSelectedFcn = createCallbackFcn(app, @add_folderMenuSelected2, true);
            app.add_folder.Text = 'Folder';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.play_and_pause = uibutton(app.UIFigure, 'push');
            app.play_and_pause.ButtonPushedFcn = createCallbackFcn(app, @play_and_pauseButtonPushed, true);
            app.play_and_pause.Text = 'Play/Stop';
            app.process = uislider(app.UIFigure);
            app.process.Limits = [0 1];
            app.process.MajorTicks = [];
            app.process.MajorTickLabels = {''};
            app.process.ValueChangedFcn = createCallbackFcn(app, @processValueChanged, true);
            app.process.MinorTicks = [];
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Play Speed';
            app.PLAYSPEED = uidropdown(app.UIFigure);
            app.PLAYSPEED.Items = {'0.5', '0.75', '1', '1.25', '1.5', '2'};
            app.PLAYSPEED.ValueChangedFcn = createCallbackFcn(app, @PLAYSPEEDValueChanged, true);
            app.PLAYSPEED.Value = '1';
            app.MusicWorksLabel = uilabel(app.UIFigure);
            app.MusicWorksLabel.Text = 'MusicWorks';
            app.StyleDropDown = uidropdown(app.UIFigure);
            app.StyleDropDown.Items = {'Auto', 'Summer', 'Dark'};
            app.StyleDropDown.ValueChangedFcn = createCallbackFcn(app, @StyleDropDownValueChanged, true);
            app.StyleDropDown.Value = 'Auto';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'hack', 'ISV-OSS-Non-Nvidia-Publishing-Matlab.png');
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked2, true);
            app.Hyperlink.Text = 'Contact Us';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MusicWorks

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end